classdef Chirag_Patel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Toolbar                      matlab.ui.container.Toolbar
        PushTool                     matlab.ui.container.toolbar.PushTool
        PushTool2                    matlab.ui.container.toolbar.PushTool
        PushTool3                    matlab.ui.container.toolbar.PushTool
        ToggleTool                   matlab.ui.container.toolbar.ToggleTool
        ToggleTool2                  matlab.ui.container.toolbar.ToggleTool
        ToggleTool3                  matlab.ui.container.toolbar.ToggleTool
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        TurnONthedevicesandsetaconnecitonwithmatlabLabel  matlab.ui.control.Label
        DeleteLabel_2                matlab.ui.control.Label
        DataImportLabel_2            matlab.ui.control.Label
        ManualOptionsCheckBox        matlab.ui.control.CheckBox
        PosH_velL_velAccnLabel_2     matlab.ui.control.Label
        ArcusSettingsCheckBox        matlab.ui.control.CheckBox
        DropDown_4                   matlab.ui.control.DropDown
        DropDown_3                   matlab.ui.control.DropDown
        dBmLabel_2                   matlab.ui.control.Label
        DropDown_2                   matlab.ui.control.DropDown
        DropDown                     matlab.ui.control.DropDown
        RangeResolutionPauseLabel    matlab.ui.control.Label
        StartAutoMeasurementButton   matlab.ui.control.Button
        currentpositionEditField     matlab.ui.control.NumericEditField
        currentpositionButton        matlab.ui.control.Button
        StepPauseEditField           matlab.ui.control.NumericEditField
        StepSizeEditField            matlab.ui.control.NumericEditField
        TimesEditField               matlab.ui.control.NumericEditField
        EditField_13                 matlab.ui.control.NumericEditField
        SetMoveModeButton            matlab.ui.control.Button
        EditField4_2                 matlab.ui.control.NumericEditField
        EditField3_3                 matlab.ui.control.NumericEditField
        EditField2_3                 matlab.ui.control.NumericEditField
        EditField_12                 matlab.ui.control.NumericEditField
        SettheParametersButton       matlab.ui.control.Button
        SetcurrentPositionto0Button  matlab.ui.control.Button
        ResetParametersButton        matlab.ui.control.Button
        posh_vell_velaccnLabel       matlab.ui.control.Label
        TextArea_2                   matlab.ui.control.TextArea
        GettheParametersButton       matlab.ui.control.Button
        AbortOperationButton         matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        Button_4                     matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        EditField_10                 matlab.ui.control.NumericEditField
        EditField_9                  matlab.ui.control.NumericEditField
        NextPeakLeftButton           matlab.ui.control.Button
        NextPeakRightButton          matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        AgilentEXA_N9010ASignalAnalyzerLabel  matlab.ui.control.Label
        AgilentMXG_N5182ASignalGeneratorLabel  matlab.ui.control.Label
        EditField_8                  matlab.ui.control.NumericEditField
        MotorStepSizeinButton        matlab.ui.control.Button
        MotorStepSizeSlider          matlab.ui.control.Slider
        MotorStepSizeSliderLabel     matlab.ui.control.Label
        dBmLabel                     matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        MotorInformationButton       matlab.ui.control.Button
        Lamp_5                       matlab.ui.control.Lamp
        ArcusMotorButton             matlab.ui.control.StateButton
        EditField_7                  matlab.ui.control.NumericEditField
        EditField_6                  matlab.ui.control.NumericEditField
        FrequencyataMarkerButton     matlab.ui.control.Button
        EditField_5                  matlab.ui.control.NumericEditField
        EditField2_2                 matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.NumericEditField
        PowerButton                  matlab.ui.control.Button
        FrequencyButton              matlab.ui.control.Button
        Lamp_2                       matlab.ui.control.Lamp
        GeneratorButton              matlab.ui.control.StateButton
        Lamp                         matlab.ui.control.Lamp
        opONOFFButton                matlab.ui.control.StateButton
        EditField_4                  matlab.ui.control.NumericEditField
        PowerataMarkerButton         matlab.ui.control.Button
        MarkersOptionsCheckBox       matlab.ui.control.CheckBox
        EditField5                   matlab.ui.control.NumericEditField
        EditField4                   matlab.ui.control.NumericEditField
        EditField3_2                 matlab.ui.control.NumericEditField
        SpanButton                   matlab.ui.control.Button
        BWButton                     matlab.ui.control.Button
        CenterFreqButton             matlab.ui.control.Button
        EditField3                   matlab.ui.control.NumericEditField
        EditField2                   matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField                    matlab.ui.control.NumericEditField
        Lamp_4                       matlab.ui.control.Lamp
        MarkeronConstantPeakSearchButton  matlab.ui.control.StateButton
        MarkerButton                 matlab.ui.control.Button
        MarkerOFFButton              matlab.ui.control.Button
        NextPeakButton               matlab.ui.control.Button
        ModePresetButton             matlab.ui.control.Button
        AutoTuneButton               matlab.ui.control.Button
        Lamp_3                       matlab.ui.control.Lamp
        AllMarkersOFFButton          matlab.ui.control.Button
        PeakSearchButton             matlab.ui.control.Button
        AnalyzerButton               matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        obj1;
        obj;
        T;
        file_name;
        GHz = 1e9;
        MHz = 1e6;
        KHz = 1e3;
        Hz  = 1;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GeneratorButton
        function GeneratorButtonValueChanged(app, event)
            value = app.GeneratorButton.Value;
            if (value==1)
                app.obj1 = Generator_MXG_N5182A();
                app.Lamp_2.Color='green';
            else
                app.obj1.Disconnect_Generator();
                app.Lamp_2.Color='red';
            end
        end

        % Value changed function: opONOFFButton
        function opONOFFButtonValueChanged(app, event)
            value = app.opONOFFButton.Value;
            if (value==1)
                app.obj1.RF_OutPut_ON();
                app.Lamp.Color='green';
            else
                app.obj1.RF_OutPut_OFF();
                app.Lamp.Color='red';
            end
        end

        % Value changed function: AnalyzerButton
        function AnalyzerButtonValueChanged(app, event)
            value = app.AnalyzerButton.Value;
            if (value==1)
                app.obj = Analyzer_EXA_N9010A();
                app.Lamp_3.Color='green';
            else
                app.obj.Disconnect_Analyzer();
                app.Lamp_3.Color='red';
            end
        end

        % Button pushed function: PeakSearchButton
        function PeakSearchButtonPushed(app, event)
            app.obj.Peak_Search(app.EditField2.Value);
        end

        % Button pushed function: AllMarkersOFFButton
        function AllMarkersOFFButtonPushed(app, event)
            app.obj.Turn_off_All_Markers();
        end

        % Button pushed function: AutoTuneButton
        function AutoTuneButtonPushed(app, event)
            app.obj.Auto_Tune();
            app.EditField3_2.Value = 3.5;
            app.DropDown_2.Value = app.DropDown_2.Items(1);
            app.EditField4.Value = 3;
            app.DropDown_4.Value = app.DropDown_4.Items(1);
            app.EditField5.Value = 6.5;
            app.DropDown_3.Value = app.DropDown_3.Items(1);
        end

        % Button pushed function: ModePresetButton
        function ModePresetButtonPushed(app, event)
            app.obj.Mode_Preset();
            app.EditField3_2.Value = 3.5;
            app.DropDown_2.Value = app.DropDown_2.Items(1);
            app.EditField4.Value = 3;
            app.DropDown_4.Value = app.DropDown_4.Items(1);
            app.EditField5.Value = 6.5;
            app.DropDown_3.Value = app.DropDown_3.Items(1);
        end

        % Button pushed function: NextPeakButton
        function NextPeakButtonPushed(app, event)
            app.obj.Next_Position(app.EditField3.Value);
        end

        % Button pushed function: MarkerOFFButton
        function MarkerOFFButtonPushed(app, event)
            app.obj.Turn_off_Marker(app.EditField_2.Value);
        end

        % Button pushed function: MarkerButton
        function MarkerButtonPushed(app, event)
            app.obj.Set_Marker(app.EditField.Value);
        end

        % Value changed function: MarkeronConstantPeakSearchButton
        function MarkeronConstantPeakSearchButtonValueChanged(app, event)
            value = app.MarkeronConstantPeakSearchButton.Value;
            if (value==1)
                app.obj.Set_Marker_onPeak(1);
                app.Lamp_4.Color='green';
            else
                app.obj.Turn_off_Marker(1);
                app.Lamp_4.Color='red';
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
          app.obj.Set_Marker(app.EditField.Value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.obj.Turn_off_Marker(app.EditField_2.Value);
        end

        % Value changed function: EditField3
        function EditField3ValueChanged(app, event)
            app.obj.Next_Position(app.EditField3.Value);
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            app.obj.Peak_Search(app.EditField2.Value);
        end

        % Button pushed function: FrequencyButton
        function FrequencyButtonPushed(app, event)
            a = app.EditField_3.Value;
            switch app.DropDown.Value
                case 'GHz'
                    app.obj1.SetFrequeny(a*app.GHz);
                case 'MHz'
                    app.obj1.SetFrequeny(a*app.MHz);
                case 'KHz'
                    app.obj1.SetFrequeny(a*app.KHz);
                case 'Hz'
                    app.obj1.SetFrequeny(a*app.Hz);    
            end
        end

        % Button pushed function: PowerButton
        function PowerButtonPushed(app, event)
            app.obj1.SetPowerLevel(app.EditField2_2.Value);
        end

        % Button pushed function: CenterFreqButton
        function CenterFreqButtonPushed(app, event)
            a = app.EditField3_2.Value;
            switch app.DropDown_2.Value
                case 'GHz'
                    app.obj.Set_Center_Frequency(a*app.GHz);
                case 'MHz'
                    app.obj.Set_Center_Frequency(a*app.MHz);
                case 'KHz'
                    app.obj.Set_Center_Frequency(a*app.KHz);
                case 'Hz'
                    app.obj.Set_Center_Frequency(a*app.Hz);    
            end
        end

        % Button pushed function: BWButton
        function BWButtonPushed(app, event)
            a = app.EditField4.Value;
            switch app.DropDown_4.Value
                case 'GHz'
                   app.obj.Set_BnadWidth(a*app.GHz);
                case 'MHz'
                    app.obj.Set_BnadWidth(a*app.MHz);
                case 'KHz'
                    app.obj.Set_BnadWidth(a*app.KHz);
                case 'Hz'
                    app.obj.Set_BnadWidth(a*app.Hz);    
            end
        end

        % Button pushed function: SpanButton
        function SpanButtonPushed(app, event)
            a = app.EditField5.Value;
            switch app.DropDown_3.Value
                case 'GHz'
                   app.obj.Set_Span(a*app.GHz);
                case 'MHz'
                    app.obj.Set_Span(a*app.MHz);
                case 'KHz'
                    app.obj.Set_Span(a*app.KHz);
                case 'Hz'
                    app.obj.Set_Span(a*app.Hz);    
            end
        end

        % Value changed function: MarkersOptionsCheckBox
        function MarkersOptionsCheckBoxValueChanged(app, event)
            value = app.MarkersOptionsCheckBox.Value;
            switch value
                case 0
                    app.MarkerButton.Visible        = 'OFF';
                    app.MarkerOFFButton.Visible     = 'OFF';
                    app.AllMarkersOFFButton.Visible = 'OFF';
                    app.NextPeakButton.Visible      = 'OFF';
                    app.PeakSearchButton.Visible    = 'OFF';
                    app.NextPeakLeftButton.Visible  = 'OFF';
                    app.NextPeakRightButton.Visible = 'OFF';
                    app.EditField.Visible   = 'OFF';
                    app.EditField_2.Visible = 'OFF';
                    app.EditField3.Visible  = 'OFF';
                    app.EditField2.Visible  = 'OFF';
                    app.EditField_10.Visible= 'OFF';
                    app.EditField_9.Visible = 'OFF';
                case 1
                    app.MarkerButton.Visible        = 'ON';
                    app.MarkerOFFButton.Visible     = 'ON';
                    app.AllMarkersOFFButton.Visible = 'ON';
                    app.NextPeakButton.Visible      = 'ON';
                    app.PeakSearchButton.Visible    = 'ON';
                    app.NextPeakLeftButton.Visible  = 'ON';
                    app.NextPeakRightButton.Visible = 'ON';
                    app.EditField.Visible   = 'ON';
                    app.EditField_2.Visible = 'ON';
                    app.EditField3.Visible  = 'ON';
                    app.EditField2.Visible  = 'ON';
                    app.EditField_10.Visible= 'ON';
                    app.EditField_9.Visible = 'ON';
            end
        end

        % Button pushed function: PowerataMarkerButton
        function PowerataMarkerButtonPushed(app, event)
           app.EditField_4.Value = str2double(app.obj.Get_Marker_Power(app.EditField_5.Value));           
        end

        % Button pushed function: FrequencyataMarkerButton
        function FrequencyataMarkerButtonPushed(app, event)
            app.EditField_7.Value = str2double(app.obj.Get_Marker_Freq(app.EditField_6.Value));
        end

        % Value changed function: ArcusMotorButton
        function ArcusMotorButtonValueChanged(app, event)
            value = app.ArcusMotorButton.Value;
            if (value==1)
                run ("MyArcus.m");
                MyArcus.Enable();
                app.Lamp_5.Color='green';
            else
                MyArcus.Disable();
                app.Lamp_5.Color='red';
            end
        end

        % Button pushed function: MotorInformationButton
        function MotorInformationButtonPushed(app, event)
            app.TextArea.Value = struct2array(MyArcus.DevInfo);
        end

        % Value changed function: MotorStepSizeSlider
        function MotorStepSizeSliderValueChanged(app, event)
            app.EditField_8.Value = app.MotorStepSizeSlider.Value;
            MyArcus.PositionTo(app.EditField_8.Value);
            app.MotorStepSizeSlider.Value = app.EditField_8.Value;
        end

        % Button pushed function: MotorStepSizeinButton
        function MotorStepSizeinButtonPushed(app, event)
              MyArcus.PositionTo(app.EditField_8.Value);
        end

        % Button pushed function: NextPeakLeftButton
        function NextPeakLeftButtonPushed(app, event)
          app.obj.Next_Peake_Left(app.EditField_10.Value);
        end

        % Button pushed function: NextPeakRightButton
        function NextPeakRightButtonPushed(app, event)
            app.obj.Next_Peake_Right(app.EditField_9.Value);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Motor_Step_Size = app.EditField_8.Value;
            Marker_Frequency= app.EditField_7.Value;
            Marker_Power    = app.EditField_4.Value;
            new_row = {Motor_Step_Size Marker_Frequency Marker_Power};
            app.UITable.Data = [app.T; new_row];
            app.T = app.UITable.Data;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if size(app.UITable.Data)   > 0 
                app.UITable.Data(end,:) = [];
                app.T(end,:)            = [];
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            MyArcus.Stop();
        end

        % Button pushed function: AbortOperationButton
        function AbortOperationButtonPushed(app, event)
            MyArcus.Abort();
        end

        % Button pushed function: GettheParametersButton
        function GettheParametersButtonPushed(app, event)
            C = struct2cell(MyArcus.getParams);
            H = cell2mat(C);
            app.TextArea_2.Value = mat2str(H);
        end

        % Button pushed function: ResetParametersButton
        function ResetParametersButtonPushed(app, event)
            MyArcus.resetStage();
        end

        % Button pushed function: SetcurrentPositionto0Button
        function SetcurrentPositionto0ButtonPushed(app, event)
            MyArcus.BackToZero();
        end

        % Button pushed function: SettheParametersButton
        function SettheParametersButtonPushed(app, event)
            C = app.EditField_12.Value; 
            H = app.EditField2_3.Value;
            I = app.EditField3_3.Value; 
            R = app.EditField4_2.Value;
           MyArcus.setParams(C,H,I,R);
        end

        % Button pushed function: SetMoveModeButton
        function SetMoveModeButtonPushed(app, event)
            MyArcus.setMoveMode(app.EditField_13.Value);
                % set move mode to INC = (1) (incremental) or ABS = (0) (abbsolute)
                % 0 is recommended for default use
        end

        % Button pushed function: currentpositionButton
        function currentpositionButtonPushed(app, event)
            app.currentpositionEditField.Value = MyArcus.Current_Pos;
        end

        % Button pushed function: StartAutoMeasurementButton
        function StartAutoMeasurementButtonPushed(app, event)
            range   = app.TimesEditField.Value;
            resolution = app.StepSizeEditField.Value;
            seconds = app.StepPauseEditField.Value;
            if range > 361
                L = imread("icons8-lol-64.png");
                msgbox(["range < 360";"Please select valid value."],...
                    "Error","custom",L);
            else
                app.UITable.Data = Auto_test(range, resolution, seconds);                
            end            
        end

        % Callback function: PushTool3
        function PushTool3Clicked(app, event)
            clear app;
            close all;
            clc
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            [file, path,~] = uiputfile('*.xlsx');
            if isnumeric(file) || isnumeric(path)
                msgbox(["Please select an excel file to Save!!";"Or Create one"],"Error");
            else
            app.file_name = [path file];      
            writetable(app.UITable.Data,app.file_name)
            winopen(app.file_name);
            end
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)
            [file, path,~] = uigetfile('*.xlsx');
            if isnumeric(file) || isnumeric(path)
               msgbox(["Please select an excel";"to import here!!"],"Error"); 
            else
            app.file_name = [path file];
            app.T = readtable(app.file_name);
            app.UITable.Data = app.T;
            end
        end

        % Value changed function: ArcusSettingsCheckBox
        function ArcusSettingsCheckBoxValueChanged(app, event)
            value = app.ArcusSettingsCheckBox.Value;
            switch value
                case 0
                    app.SetcurrentPositionto0Button.Visible = 'OFF';
                    app.ResetParametersButton.Visible       = 'OFF';
                    app.SetMoveModeButton.Visible           = 'OFF';
                    app.EditField_13.Visible                = 'OFF';
                    app.GettheParametersButton.Visible      = 'OFF';
                    app.TextArea_2.Visible                  = 'OFF';
                    app.posh_vell_velaccnLabel.Visible      = 'OFF';
                    app.SettheParametersButton.Visible      = 'OFF';
                    app.EditField_12.Visible                = 'OFF';
                    app.EditField2_3.Visible                = 'OFF';
                    app.EditField3_3.Visible                = 'OFF';
                    app.EditField4_2.Visible                = 'OFF';
                    app.PosH_velL_velAccnLabel_2.Visible    = 'OFF';
                case 1
                    app.SetcurrentPositionto0Button.Visible = 'ON';
                    app.ResetParametersButton.Visible       = 'ON';
                    app.SetMoveModeButton.Visible           = 'ON';
                    app.EditField_13.Visible                = 'ON';
                    app.GettheParametersButton.Visible      = 'ON';
                    app.TextArea_2.Visible                  = 'ON';
                    app.posh_vell_velaccnLabel.Visible      = 'ON';
                    app.SettheParametersButton.Visible      = 'ON';
                    app.EditField_12.Visible                = 'ON';
                    app.EditField2_3.Visible                = 'ON';
                    app.EditField3_3.Visible                = 'ON';
                    app.EditField4_2.Visible                = 'ON';
                    app.PosH_velL_velAccnLabel_2.Visible    = 'ON';
            end
        end

        % Value changed function: ManualOptionsCheckBox
        function ManualOptionsCheckBoxValueChanged(app, event)
            value = app.ManualOptionsCheckBox.Value;
            switch value
                case 0
                    app.MarkeronConstantPeakSearchButton.Visible = 'OFF';
                    app.FrequencyataMarkerButton.Visible         = 'OFF';
                    app.EditField_6.Visible                      = 'OFF';
                    app.EditField_7.Visible                      = 'OFF';
                    app.PowerataMarkerButton.Visible             = 'OFF';
                    app.EditField_5.Visible                      = 'OFF';
                    app.EditField_4.Visible                      = 'OFF';
                    app.dBmLabel.Visible                         = 'OFF';
                    app.Button.Visible                           = 'OFF';
                    app.Button_4.Visible                         = 'OFF';
                    app.DataImportLabel_2.Visible                = 'OFF';
                    app.Button_4.Visible                         = 'OFF';
                    app.DeleteLabel_2.Visible                    = 'OFF';
                    app.Lamp_4.Visible                           = 'OFF';
                    app.AutoTuneButton.Visible                   = 'OFF';
                    app.ModePresetButton.Visible                 = 'OFF';
                case 1 
                    app.MarkeronConstantPeakSearchButton.Visible = 'ON';
                    app.FrequencyataMarkerButton.Visible         = 'ON';
                    app.EditField_6.Visible                      = 'ON';
                    app.EditField_7.Visible                      = 'ON';
                    app.PowerataMarkerButton.Visible             = 'ON';
                    app.EditField_5.Visible                      = 'ON';
                    app.EditField_4.Visible                      = 'ON';
                    app.dBmLabel.Visible                         = 'ON';
                    app.Button.Visible                           = 'ON';
                    app.Button_4.Visible                         = 'ON';
                    app.DataImportLabel_2.Visible                = 'ON';
                    app.Button_4.Visible                         = 'ON';
                    app.DeleteLabel_2.Visible                    = 'ON';
                    app.Lamp_4.Visible                           = 'ON';
                    app.AutoTuneButton.Visible                   = 'ON';
                    app.ModePresetButton.Visible                 = 'ON';
            end
        end

        % On callback: ToggleTool
        function ToggleToolOn(app, event)
            value = app.ToggleToolOn.Value;
            switch value
                case 0
                    app.MarkeronConstantPeakSearchButton.Visible = 'OFF';
                    app.FrequencyataMarkerButton.Visible         = 'OFF';
                    app.EditField_6.Visible                      = 'OFF';
                    app.EditField_7.Visible                      = 'OFF';
                    app.PowerataMarkerButton.Visible             = 'OFF';
                    app.EditField_5.Visible                      = 'OFF';
                    app.EditField_4.Visible                      = 'OFF';
                    app.dBmLabel.Visible                         = 'OFF';
                    app.Button.Visible                           = 'OFF';
                    app.Button_4.Visible                         = 'OFF';
                    app.DataImportLabel_2.Visible                = 'OFF';
                    app.Button_4.Visible                         = 'OFF';
                    app.DeleteLabel_2.Visible                    = 'OFF';
                    app.Lamp_4.Visible                           = 'OFF';
                    app.AutoTuneButton.Visible                   = 'OFF';
                    app.ModePresetButton.Visible                 = 'OFF';
                case 1 
                    app.MarkeronConstantPeakSearchButton.Visible = 'ON';
                    app.FrequencyataMarkerButton.Visible         = 'ON';
                    app.EditField_6.Visible                      = 'ON';
                    app.EditField_7.Visible                      = 'ON';
                    app.PowerataMarkerButton.Visible             = 'ON';
                    app.EditField_5.Visible                      = 'ON';
                    app.EditField_4.Visible                      = 'ON';
                    app.dBmLabel.Visible                         = 'ON';
                    app.Button.Visible                           = 'ON';
                    app.Button_4.Visible                         = 'ON';
                    app.DataImportLabel_2.Visible                = 'ON';
                    app.Button_4.Visible                         = 'ON';
                    app.DeleteLabel_2.Visible                    = 'ON';
                    app.Lamp_4.Visible                           = 'ON';
                    app.AutoTuneButton.Visible                   = 'ON';
                    app.ModePresetButton.Visible                 = 'ON';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Save Table data'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'icons8-save-close-48.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Open Excel File '};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'icons8-open-file-folder-48.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'clear variables and internal data'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = 'icons8-update-left-rotation-48.png';
            app.ToggleTool = uitoggletool(app.Toolbar);
            app.ToggleTool.Icon = fullfile(pathToMLAPP, 'EXA.png');
            app.ToggleTool.Separator = 'on';
            app.ToggleTool.OnCallback = createCallbackFcn(app, @ToggleToolOn, true);
            app.ToggleTool.State = 'on';
            app.ToggleTool2 = uitoggletool(app.Toolbar);
            app.ToggleTool2.Icon = fullfile(pathToMLAPP, 'Marker.png');
            app.ToggleTool2.State = 'on';
            app.ToggleTool3 = uitoggletool(app.Toolbar);
            app.ToggleTool3.Icon = fullfile(pathToMLAPP, 'Arcus.png');
            app.ToggleTool3.Separator = 'on';
            app.ToggleTool3.State = 'on';
            app.AnalyzerButton = uibutton(app.UIFigure, 'state');
            app.AnalyzerButton.ValueChangedFcn = createCallbackFcn(app, @AnalyzerButtonValueChanged, true);
            app.AnalyzerButton.Text = 'Analyzer  ';
            app.PeakSearchButton = uibutton(app.UIFigure, 'push');
            app.PeakSearchButton.ButtonPushedFcn = createCallbackFcn(app, @PeakSearchButtonPushed, true);
            app.PeakSearchButton.Visible = 'off';
            app.PeakSearchButton.Text = '  Peak Search';
            app.AllMarkersOFFButton = uibutton(app.UIFigure, 'push');
            app.AllMarkersOFFButton.ButtonPushedFcn = createCallbackFcn(app, @AllMarkersOFFButtonPushed, true);
            app.AllMarkersOFFButton.Visible = 'off';
            app.AllMarkersOFFButton.Text = 'All Markers OFF';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.AutoTuneButton = uibutton(app.UIFigure, 'push');
            app.AutoTuneButton.ButtonPushedFcn = createCallbackFcn(app, @AutoTuneButtonPushed, true);
            app.AutoTuneButton.Visible = 'off';
            app.AutoTuneButton.Text = 'Auto Tune';
            app.ModePresetButton = uibutton(app.UIFigure, 'push');
            app.ModePresetButton.ButtonPushedFcn = createCallbackFcn(app, @ModePresetButtonPushed, true);
            app.ModePresetButton.Visible = 'off';
            app.ModePresetButton.Text = 'Mode Preset';
            app.NextPeakButton = uibutton(app.UIFigure, 'push');
            app.NextPeakButton.ButtonPushedFcn = createCallbackFcn(app, @NextPeakButtonPushed, true);
            app.NextPeakButton.Visible = 'off';
            app.NextPeakButton.Text = '  Next Peak';
            app.MarkerOFFButton = uibutton(app.UIFigure, 'push');
            app.MarkerOFFButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerOFFButtonPushed, true);
            app.MarkerOFFButton.Visible = 'off';
            app.MarkerOFFButton.Text = '  Marker OFF';
            app.MarkerButton = uibutton(app.UIFigure, 'push');
            app.MarkerButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerButtonPushed, true);
            app.MarkerButton.Visible = 'off';
            app.MarkerButton.Tooltip = {'Put Marker and'; 'select a number'};
            app.MarkerButton.Text = 'Marker';
            app.MarkeronConstantPeakSearchButton = uibutton(app.UIFigure, 'state');
            app.MarkeronConstantPeakSearchButton.ValueChangedFcn = createCallbackFcn(app, @MarkeronConstantPeakSearchButtonValueChanged, true);
            app.MarkeronConstantPeakSearchButton.Visible = 'off';
            app.MarkeronConstantPeakSearchButton.Text = ' Marker on Constant Peak Search ';
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_4.Visible = 'off';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [1 5];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Visible = 'off';
            app.EditField.Value = 1;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [1 5];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Visible = 'off';
            app.EditField_2.Value = 1;
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.Limits = [1 5];
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.EditField2.Visible = 'off';
            app.EditField2.Value = 1;
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.Limits = [1 5];
            app.EditField3.ValueChangedFcn = createCallbackFcn(app, @EditField3ValueChanged, true);
            app.EditField3.Visible = 'off';
            app.EditField3.Value = 1;
            app.CenterFreqButton = uibutton(app.UIFigure, 'push');
            app.CenterFreqButton.ButtonPushedFcn = createCallbackFcn(app, @CenterFreqButtonPushed, true);
            app.CenterFreqButton.Text = {'  Center '; '   Freq.'};
            app.BWButton = uibutton(app.UIFigure, 'push');
            app.BWButton.ButtonPushedFcn = createCallbackFcn(app, @BWButtonPushed, true);
            app.BWButton.Tooltip = {'Bandwidth'};
            app.BWButton.Text = '  BW';
            app.SpanButton = uibutton(app.UIFigure, 'push');
            app.SpanButton.ButtonPushedFcn = createCallbackFcn(app, @SpanButtonPushed, true);
            app.SpanButton.Text = '  Span';
            app.EditField3_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3_2.Tooltip = {'9KHz(9e5) to'; '7GHz(7e9)'};
            app.EditField3_2.Value = 3.5;
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4.Value = 3;
            app.EditField5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField5.Value = 6.5;
            app.MarkersOptionsCheckBox = uicheckbox(app.UIFigure);
            app.MarkersOptionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarkersOptionsCheckBoxValueChanged, true);
            app.MarkersOptionsCheckBox.Text = 'Marker''s Options';
            app.PowerataMarkerButton = uibutton(app.UIFigure, 'push');
            app.PowerataMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @PowerataMarkerButtonPushed, true);
            app.PowerataMarkerButton.Visible = 'off';
            app.PowerataMarkerButton.Text = {'Power '; 'at a Marker'};
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Visible = 'off';
            app.opONOFFButton = uibutton(app.UIFigure, 'state');
            app.opONOFFButton.ValueChangedFcn = createCallbackFcn(app, @opONOFFButtonValueChanged, true);
            app.opONOFFButton.Tag = 'state button on and off';
            app.opONOFFButton.Tooltip = {'Gen. RF Output'};
            app.opONOFFButton.Text = 'o/p ON/OFF';
            app.Lamp = uilamp(app.UIFigure);
            app.GeneratorButton = uibutton(app.UIFigure, 'state');
            app.GeneratorButton.ValueChangedFcn = createCallbackFcn(app, @GeneratorButtonValueChanged, true);
            app.GeneratorButton.Text = 'Generator ';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.FrequencyButton = uibutton(app.UIFigure, 'push');
            app.FrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyButtonPushed, true);
            app.FrequencyButton.Tooltip = {'Set Frequency'};
            app.FrequencyButton.Text = 'Frequency';
            app.PowerButton = uibutton(app.UIFigure, 'push');
            app.PowerButton.ButtonPushedFcn = createCallbackFcn(app, @PowerButtonPushed, true);
            app.PowerButton.Tooltip = {'Set Power dBm'};
            app.PowerButton.Text = '  Power';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Tooltip = {'100KHz(1e5) to 6GHz(1e9)'};
            app.EditField_3.Value = 1;
            app.EditField2_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_2.Limits = [-110 17];
            app.EditField2_2.Tooltip = {'-110dBm to 17dBm'};
            app.EditField2_2.Value = 10;
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Visible = 'off';
            app.EditField_5.Value = 1;
            app.FrequencyataMarkerButton = uibutton(app.UIFigure, 'push');
            app.FrequencyataMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyataMarkerButtonPushed, true);
            app.FrequencyataMarkerButton.Visible = 'off';
            app.FrequencyataMarkerButton.Text = {'Frequency'; 'at a Marker'};
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Visible = 'off';
            app.EditField_6.Value = 1;
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Visible = 'off';
            app.ArcusMotorButton = uibutton(app.UIFigure, 'state');
            app.ArcusMotorButton.ValueChangedFcn = createCallbackFcn(app, @ArcusMotorButtonValueChanged, true);
            app.ArcusMotorButton.Text = 'Arcus Motor';
            app.Lamp_5 = uilamp(app.UIFigure);
            app.MotorInformationButton = uibutton(app.UIFigure, 'push');
            app.MotorInformationButton.ButtonPushedFcn = createCallbackFcn(app, @MotorInformationButtonPushed, true);
            app.MotorInformationButton.Text = {' Motor'; 'Information'};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.dBmLabel = uilabel(app.UIFigure);
            app.dBmLabel.Visible = 'off';
            app.dBmLabel.Text = 'dBm';
            app.MotorStepSizeSliderLabel = uilabel(app.UIFigure);
            app.MotorStepSizeSliderLabel.Text = 'Motor Step Size';
            app.MotorStepSizeSlider = uislider(app.UIFigure);
            app.MotorStepSizeSlider.Limits = [0 360];
            app.MotorStepSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MotorStepSizeSliderValueChanged, true);
            app.MotorStepSizeinButton = uibutton(app.UIFigure, 'push');
            app.MotorStepSizeinButton.ButtonPushedFcn = createCallbackFcn(app, @MotorStepSizeinButtonPushed, true);
            app.MotorStepSizeinButton.Text = {'Motor Step'; 'Size in ( °)'};
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.AgilentMXG_N5182ASignalGeneratorLabel = uilabel(app.UIFigure);
            app.AgilentMXG_N5182ASignalGeneratorLabel.Text = 'Agilent MXG_N5182A Signal Generator ...............................................................................................................................';
            app.AgilentEXA_N9010ASignalAnalyzerLabel = uilabel(app.UIFigure);
            app.AgilentEXA_N9010ASignalAnalyzerLabel.Text = 'Agilent EXA_N9010A Signal Analyzer...................................................................................................................................';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'No.'; 'Steps'; 'Power'; 'Freq'};
            app.UITable.RowName = {};
            app.NextPeakRightButton = uibutton(app.UIFigure, 'push');
            app.NextPeakRightButton.ButtonPushedFcn = createCallbackFcn(app, @NextPeakRightButtonPushed, true);
            app.NextPeakRightButton.Visible = 'off';
            app.NextPeakRightButton.Text = {'Next Peak'; '   Right'};
            app.NextPeakLeftButton = uibutton(app.UIFigure, 'push');
            app.NextPeakLeftButton.ButtonPushedFcn = createCallbackFcn(app, @NextPeakLeftButtonPushed, true);
            app.NextPeakLeftButton.Visible = 'off';
            app.NextPeakLeftButton.Text = {'Next Peak'; '     Left'};
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.Visible = 'off';
            app.EditField_9.Value = 1;
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10.Visible = 'off';
            app.EditField_10.Value = 1;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icons8-import-48.png';
            app.Button.Visible = 'off';
            app.Button.Tooltip = {'Import to the Table'};
            app.Button.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'icons8-delete-key-100.png';
            app.Button_4.Visible = 'off';
            app.Button_4.Tooltip = {'Delete last row'};
            app.Button_4.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '°';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.AbortOperationButton = uibutton(app.UIFigure, 'push');
            app.AbortOperationButton.ButtonPushedFcn = createCallbackFcn(app, @AbortOperationButtonPushed, true);
            app.AbortOperationButton.Text = 'Abort Operation';
            app.GettheParametersButton = uibutton(app.UIFigure, 'push');
            app.GettheParametersButton.ButtonPushedFcn = createCallbackFcn(app, @GettheParametersButtonPushed, true);
            app.GettheParametersButton.Visible = 'off';
            app.GettheParametersButton.Text = {'Get the'; 'Parameters'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Visible = 'off';
            app.posh_vell_velaccnLabel = uilabel(app.UIFigure);
            app.posh_vell_velaccnLabel.Visible = 'off';
            app.posh_vell_velaccnLabel.Text = '[pos; h_vel; l_vel; accn]';
            app.ResetParametersButton = uibutton(app.UIFigure, 'push');
            app.ResetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetParametersButtonPushed, true);
            app.ResetParametersButton.Visible = 'off';
            app.ResetParametersButton.Text = 'Reset Parameters';
            app.SetcurrentPositionto0Button = uibutton(app.UIFigure, 'push');
            app.SetcurrentPositionto0Button.ButtonPushedFcn = createCallbackFcn(app, @SetcurrentPositionto0ButtonPushed, true);
            app.SetcurrentPositionto0Button.Visible = 'off';
            app.SetcurrentPositionto0Button.Text = 'Set current Position to 0°';
            app.SettheParametersButton = uibutton(app.UIFigure, 'push');
            app.SettheParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SettheParametersButtonPushed, true);
            app.SettheParametersButton.Visible = 'off';
            app.SettheParametersButton.Text = {'Set the'; 'Parameters'};
            app.EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_12.Limits = [0 360];
            app.EditField_12.Visible = 'off';
            app.EditField2_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_3.Limits = [100 20000];
            app.EditField2_3.Visible = 'off';
            app.EditField2_3.Value = 1000;
            app.EditField3_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3_3.Limits = [100 20000];
            app.EditField3_3.Visible = 'off';
            app.EditField3_3.Value = 100;
            app.EditField4_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4_2.Limits = [10 1000];
            app.EditField4_2.Visible = 'off';
            app.EditField4_2.Value = 300;
            app.SetMoveModeButton = uibutton(app.UIFigure, 'push');
            app.SetMoveModeButton.ButtonPushedFcn = createCallbackFcn(app, @SetMoveModeButtonPushed, true);
            app.SetMoveModeButton.Visible = 'off';
            app.SetMoveModeButton.Text = 'Set Move Mode';
            app.EditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_13.Limits = [0 1];
            app.EditField_13.Visible = 'off';
            app.EditField_13.Tooltip = {'''ABS" = (0) or ''INC'' = (1)'};
            app.TimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimesEditField.Tooltip = {'0 to 360'};
            app.StepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepSizeEditField.Tooltip = {'Range / " Resolution " = accuracy'};
            app.StepPauseEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepPauseEditField.Tooltip = {'Pause in Seconds'};
            app.currentpositionButton = uibutton(app.UIFigure, 'push');
            app.currentpositionButton.ButtonPushedFcn = createCallbackFcn(app, @currentpositionButtonPushed, true);
            app.currentpositionButton.Tooltip = {'The motor is at this position Now'};
            app.currentpositionButton.Text = 'current position';
            app.currentpositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartAutoMeasurementButton = uibutton(app.UIFigure, 'push');
            app.StartAutoMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StartAutoMeasurementButtonPushed, true);
            app.StartAutoMeasurementButton.Icon = 'icons8-automation-64 (2).png';
            app.StartAutoMeasurementButton.Text = {'Start Auto'; 'Measurement'};
            app.RangeResolutionPauseLabel = uilabel(app.UIFigure);
            app.RangeResolutionPauseLabel.Text = 'Range,  Resolution,  Pause';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'GHz', 'MHz', 'KHz', 'Hz'};
            app.DropDown.Value = 'GHz';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'GHz', 'MHz', 'KHz', 'Hz'};
            app.DropDown_2.Value = 'GHz';
            app.dBmLabel_2 = uilabel(app.UIFigure);
            app.dBmLabel_2.Text = 'dBm';
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.DropDown_3.Items = {'GHz', 'MHz', 'KHz', 'Hz'};
            app.DropDown_3.Value = 'GHz';
            app.DropDown_4 = uidropdown(app.UIFigure);
            app.DropDown_4.Items = {'GHz', 'MHz', 'KHz', 'Hz'};
            app.DropDown_4.Value = 'GHz';
            app.ArcusSettingsCheckBox = uicheckbox(app.UIFigure);
            app.ArcusSettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArcusSettingsCheckBoxValueChanged, true);
            app.ArcusSettingsCheckBox.Text = 'Arcus Settings';
            app.PosH_velL_velAccnLabel_2 = uilabel(app.UIFigure);
            app.PosH_velL_velAccnLabel_2.Visible = 'off';
            app.PosH_velL_velAccnLabel_2.Text = 'Pos ( °);  H_vel;    L_vel;   Accn';
            app.ManualOptionsCheckBox = uicheckbox(app.UIFigure);
            app.ManualOptionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualOptionsCheckBoxValueChanged, true);
            app.ManualOptionsCheckBox.Text = 'Manual Options';
            app.DataImportLabel_2 = uilabel(app.UIFigure);
            app.DataImportLabel_2.Visible = 'off';
            app.DataImportLabel_2.Text = 'Data Import';
            app.DeleteLabel_2 = uilabel(app.UIFigure);
            app.DeleteLabel_2.Visible = 'off';
            app.DeleteLabel_2.Text = 'Delete';
            app.TurnONthedevicesandsetaconnecitonwithmatlabLabel = uilabel(app.UIFigure);
            app.TurnONthedevicesandsetaconnecitonwithmatlabLabel.Text = 'Turn ON  the devices and set a conneciton with matlab.....................................................................';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '°';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '°';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chirag_Patel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end