classdef Polar_Diagram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        WritedataButton     matlab.ui.control.Button
        readfileButton      matlab.ui.control.Button
        adddataButton       matlab.ui.control.Button
        UITable             matlab.ui.control.Table
        nameEditField       matlab.ui.control.EditField
        nameEditFieldLabel  matlab.ui.control.Label
        ageEditField        matlab.ui.control.NumericEditField
        ageEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        table; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: adddataButton
        function adddataButtonPushed(app, event)
            name = app.nameEditField.Value;
            age  = app.ageEditField.Value;
            new_row = {name age};
            app.UITable.Data = [app.table; new_row];
            app.table = app.UITable.Data;
        end

        % Button pushed function: readfileButton
        function readfileButtonPushed(app, event)
            app.table = readtable('C:\Users\User_Antenne\Desktop\stdent data.xlsx', 'sheet', 1);
            app.UITable.Data = app.table;
        end

        % Button pushed function: WritedataButton
        function WritedataButtonPushed(app, event)
            file_name = 'C:\Users\User_Antenne\Desktop\stdent data.xlsx';
            writetable(app.table,file_name);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ageEditFieldLabel = uilabel(app.UIFigure);
            app.ageEditFieldLabel.Text = 'age';
            app.ageEditField = uieditfield(app.UIFigure, 'numeric');
            app.nameEditFieldLabel = uilabel(app.UIFigure);
            app.nameEditFieldLabel.Text = 'name ';
            app.nameEditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.adddataButton = uibutton(app.UIFigure, 'push');
            app.adddataButton.ButtonPushedFcn = createCallbackFcn(app, @adddataButtonPushed, true);
            app.adddataButton.Text = 'add data';
            app.readfileButton = uibutton(app.UIFigure, 'push');
            app.readfileButton.ButtonPushedFcn = createCallbackFcn(app, @readfileButtonPushed, true);
            app.readfileButton.Text = 'read file';
            app.WritedataButton = uibutton(app.UIFigure, 'push');
            app.WritedataButton.ButtonPushedFcn = createCallbackFcn(app, @WritedataButtonPushed, true);
            app.WritedataButton.Text = 'Write data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Polar_Diagram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end